classdef ssmview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        openSSMButton        matlab.ui.control.Button
        UpdateweightsButton  matlab.ui.control.Button
        STATISTICALSHAPEMODELVISUALIZATIONLabel  matlab.ui.control.Label
        Coefficientsforthefirst5principalcomponentsPanel  matlab.ui.container.Panel
        alpha_1Label         matlab.ui.control.Label
        a1Slider             matlab.ui.control.Slider
        a2SliderLabel        matlab.ui.control.Label
        a2Slider             matlab.ui.control.Slider
        a3SliderLabel        matlab.ui.control.Label
        a3Slider             matlab.ui.control.Slider
        a4SliderLabel        matlab.ui.control.Label
        a4Slider             matlab.ui.control.Slider
        a5SliderLabel        matlab.ui.control.Label
        a5Slider             matlab.ui.control.Slider
    end

    
    properties (Access = public)
        Model % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openSSMButton
        function openSSMButtonPushed(app, event)
            [filename1,filepath1]=uigetfile({'*.*','All Files'},...
                  'Select Data File 1');
            cd(filepath1);
            model=load([filepath1 filename1]);
            f = fields(model);
            mu = model.(f{1}).MEAN;
            h = app.UIAxes;
            patch(h,'faces', mu.Faces,'vertices', mu.Vertices,...
                'edgecolor','none','facecolor','b','facealpha',0.5);
            h.DataAspectRatio = [1 1 1];
            app.Model = model.(f{1});
        end

        % Button pushed function: UpdateweightsButton
        function UpdateweightsButtonPushed(app, event)
            model = app.Model;
            mu = model.MEAN;
            a1 = app.a1Slider.Value;
            a2 = app.a2Slider.Value;
            a3 = app.a3Slider.Value;
            a4 = app.a4Slider.Value;
            a5 = app.a5Slider.Value;
            Q = model.COEFF*diag(sqrt(model.LATENT));
            Q = Q(:,1:5);
            a = [a1 a2 a3 a4 a5]';
            mu_r = [mu.Vertices(:,1);mu.Vertices(:,2);mu.Vertices(:,3)];
            new = mu_r + Q*a;
            nel = numel(new)/3;
            new = [new(1:nel) new(nel+1:2*nel) new(2*nel+1:3*nel)];
            h = app.UIAxes;
            delete(h.Children)
            patch(h,'faces', mu.Faces,'vertices', mu.Vertices,...
                'edgecolor','none','facecolor','b','facealpha',0.5);
            h.DataAspectRatio = [1 1 1];
            patch(h,'faces', mu.Faces,'vertices', new,...
                'edgecolor','none','facecolor','g','facealpha',0.5);
            %axis equal; camlight;lighting gouraud
            h.DataAspectRatio = [1 1 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.openSSMButton = uibutton(app.UIFigure, 'push');
            app.openSSMButton.ButtonPushedFcn = createCallbackFcn(app, @openSSMButtonPushed, true);
            app.openSSMButton.Text = 'open SSM';
            app.UpdateweightsButton = uibutton(app.UIFigure, 'push');
            app.UpdateweightsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateweightsButtonPushed, true);
            app.UpdateweightsButton.Text = 'Update weights';
            app.STATISTICALSHAPEMODELVISUALIZATIONLabel = uilabel(app.UIFigure);
            app.STATISTICALSHAPEMODELVISUALIZATIONLabel.Text = 'STATISTICAL SHAPE MODEL VISUALIZATION';
            app.Coefficientsforthefirst5principalcomponentsPanel = uipanel(app.UIFigure);
            app.Coefficientsforthefirst5principalcomponentsPanel.Title = 'Coefficients for the first 5 principal components';
            app.alpha_1Label = uilabel(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.alpha_1Label.Text = 'a1';
            app.a1Slider = uislider(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a1Slider.Limits = [-3 3];
            app.a2SliderLabel = uilabel(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a2SliderLabel.Text = 'a2';
            app.a2Slider = uislider(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a2Slider.Limits = [-3 3];
            app.a3SliderLabel = uilabel(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a3SliderLabel.Text = 'a3';
            app.a3Slider = uislider(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a3Slider.Limits = [-3 3];
            app.a4SliderLabel = uilabel(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a4SliderLabel.Text = 'a4';
            app.a4Slider = uislider(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a4Slider.Limits = [-3 3];
            app.a5SliderLabel = uilabel(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a5SliderLabel.Text = 'a5';
            app.a5Slider = uislider(app.Coefficientsforthefirst5principalcomponentsPanel);
            app.a5Slider.Limits = [-3 3];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ssmview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end